classdef KalmanGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % Q
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % A
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        UIAxes                 matlab.ui.control.UIAxes           % Reach D...
        Button                 matlab.ui.control.Button           % Update ...
        LabelDropDown          matlab.ui.control.Label            % Reach D...
        DropDown               matlab.ui.control.DropDown         % Up, Dow...
        LabelSlider            matlab.ui.control.Label            % Input N...
        Slider                 matlab.ui.control.Slider           % [1.0000...
        LabelSlider2           matlab.ui.control.Label            % Signal ...
        Slider2                matlab.ui.control.Slider           % [1.0000...
        UIAxes2                matlab.ui.control.UIAxes           % Predict...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        duration = 10;
        dt = 0.1;
        dir = app.DropDown.Value;
        inp_ns = app.Slider.Value;
        sig_ns = app.Slider2.Value;
        [loc loc_meas loc_est] = Kalman_filter(duration, dt, dir, inp_ns, sig_ns);
        figure
        %make a line to the final point and the final predicted point
        x1 = [0 loc(end,1)];
        y1 = [0 loc(end,2)];
        p1 = polyfit(x1,y1,1);
        f1 = polyval(p1,x1);
        plot(app.UIAxes, x1, f1, 'r', 'LineWidth', 0.5)
        axes_limits = ([loc(end,1) loc(end,2)]);
        axes_limit = max(abs(axes_limits));
        xlim(app.UIAxes, [-abs(axes_limit) abs(axes_limit)])
        ylim(app.UIAxes, [-abs(axes_limit) abs(axes_limit)])
        hold on
        x2 = [0 loc(end,1)];
        y2 = [0 loc(end,2)];
        p2 = polyfit(x2,y2,1);
        f2 = polyval(p2,x2);
        plot(app.UIAxes2, x2, f2, 'g', 'LineWidth', 0.1)
        axes_limits = ([loc_est(end,1) loc_est(end,2)]);
        axes_limit = max(abs(axes_limits));
        xlim(app.UIAxes2, [-abs(axes_limit) abs(axes_limit)])
        ylim(app.UIAxes2, [-abs(axes_limit) abs(axes_limit)])
        hold off
        %plot(app.UIAxes, loc_est(end,1), loc_est(end,2), sz, 'g')
        scatter3([0:dt:duration], loc(:,1),loc(:,2), 'r') %, 
        hold on 
        scatter3([0:dt:duration],loc_meas(:,1), loc_meas(:,2), 'b') %, loc_meas(:,2)
        scatter3([0:dt:duration],loc_est(:,1), loc_est(:,2), 'g')
        hold off

        end

        % Button button pushed function
        function ButtonButtonPushed(app)
       
        duration = 10;
        dt = 0.1;
        dir = app.DropDown.Value;
        inp_ns = app.Slider.Value;
        sig_ns = app.Slider2.Value;
        [loc loc_meas loc_est] = Kalman_filter(duration, dt, dir,  inp_ns, sig_ns);
        figure
        %make a line to the final point and the final predicted point
        x1 = [0 loc(end,1)];
        y1 = [0 loc(end,2)];
        p1 = polyfit(x1,y1,1);
        f1 = polyval(p1,x1);
        plot(app.UIAxes, x1, f1, 'r', 'LineWidth', 0.5)
        axes_limits = ([loc(end,1) loc(end,2)]);
        axes_limit = max(abs(axes_limits));
        xlim(app.UIAxes, [-abs(axes_limit) abs(axes_limit)])
        ylim(app.UIAxes, [-abs(axes_limit) abs(axes_limit)])
        hold on
        x2 = [0 loc(end,1)];
        y2 = [0 loc(end,2)];
        p2 = polyfit(x2,y2,1);
        f2 = polyval(p2,x2);
        plot(app.UIAxes2, x2, f2, 'g', 'LineWidth', 0.1)
        hold off
        %plot(app.UIAxes, loc_est(end,1), loc_est(end,2), sz, 'g')
        scatter3([0:dt:duration], loc(:,1),loc(:,2), 'r') %, 
        hold on 
        scatter3([0:dt:duration],loc_meas(:,1), loc_meas(:,2), 'b') %, loc_meas(:,2)
        scatter3([0:dt:duration],loc_est(:,1), loc_est(:,2), 'g')
        hold off
            
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value(1) == 'U'
                reach = [0 1; 0 1;];
            elseif value(1) == 'D'
                reach = [0 -1; 0 -1;];
            elseif value(1) == 'R'
                reach = [1 0; 1 0;];
            elseif value(1) == 'L'
                reach = [-1 0; -1 0;];
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Q';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'A';
            app.NumericEditField2 = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reach Direction');
            xlabel(app.UIAxes, 'X (mm)');
            ylabel(app.UIAxes, 'Y (mm)');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Update results';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Reach Direction';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Up', 'Down', 'Left', 'Right'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Up';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Input Noise';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0.01 10];
            app.Slider.MajorTickLabels = {'0.01', '', '', '', '', '10'};
            app.Slider.Value = 0.05;
            app.LabelSlider2 = uilabel(app.UIFigure);
            app.LabelSlider2.Text = 'Signal Noise';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [0.01 10];
            app.Slider2.MajorTickLabels = {'0.01', '', '', '', '', '10'};
            app.Slider2.Value = 10;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Predicted Direction');
            xlabel(app.UIAxes2, 'X (mm)');
            ylabel(app.UIAxes2, 'Y (mm)');
        end
    end

    methods (Access = public)

        % Construct app
        function app = KalmanGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end